classdef COPGuiOOP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COP                        matlab.ui.Figure
        COPplot                    matlab.ui.control.UIAxes
        FolderSelectButton         matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        AcceptButton               matlab.ui.control.Button
        dataTable                  matlab.ui.control.Table
        RecordButton               matlab.ui.control.Button
        SubjectEditFieldLabel      matlab.ui.control.Label
        SubjectEditField           matlab.ui.control.EditField
        ListBox                    matlab.ui.control.ListBox
        OutputfolderButton         matlab.ui.control.Button
        Output                     matlab.ui.control.Label
        YMinMaxEditFieldLabel      matlab.ui.control.Label
        YMinMaxEditField           matlab.ui.control.NumericEditField
        XMinMaxEditFieldLabel      matlab.ui.control.Label
        XMinMaxEditField           matlab.ui.control.NumericEditField
        IncludeUnfilteredCheckBox  matlab.ui.control.CheckBox
        ResetButton                matlab.ui.control.Button
    end


    %This gui will process COP data. V1.0 is not built to handle errors so if the steps are not done in order it may cause unknown errors
    %1.Select input and output folders;2.Identify the duration of the trial with the end time spinner that can set a value between 5-30 seconds(steps by 5);
    %3. Type in subject name next to subject(this will be the name of the output excel file; 4. select what trial from the list on the bottom right side;
    %6. Click accept and wait until the plot and table appears 7. if the values look acceptible and the subject name in the table matches the one you want to process click on record
    %8. You can record 1 PSopen and 1 PSclosed per excel sheet(they save as 2 different pages)
    properties (Access = protected)
        type = "tsv"
        outpath;
        Condition;
        currentFile;
        fileStr;
        fileName;
        filePath char;
        defaultFilePath =  '';
        fileList;
        fileSel;
        COPdata;%COPdataSet
        COPfileIn;%CoPfileInput
        RowCounter = 1;
        tableBuilder = [];
        tableHolder;
        header = {'Subject','Condition','Time','Direction','RMS', 'MDIST', 'TOTEX','MVELO','MFREQ','AREACC','AREACE','AREASW'};
        subject;
        endTime;
 
    end
    properties
       AP;
       ML;
    end


    properties(Access = private)
        isRecorded=false;
        isUnfiltOn = true;
    end

    methods (Access = private)
    
        function fileListPop(app)
            tsvfiles = dir(fullfile(app.fileStr,'*.tsv'));
            if(~isempty(tsvfiles))
                app.type = "tsv";
            end
            forcesfiles = dir(fullfile(app.fileStr,'*.forces'));
            if(~isempty(forcesfiles))
                app.type = "forces";
            end
            files = [tsvfiles,forcesfiles];
            app.Label.Text = app.fileStr;
            app.fileList = {files.name};
            app.ListBox.Items = app.fileList;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.defaultFilePath = 'C:\';
            app.dataTable.ColumnName = app.header;
            app.tableBuilder = app.header;
            app.endTime = 30;
            app.fileStr = app.defaultFilePath;
            fileListPop(app);
            app.outpath = app.defaultFilePath;
            
        end

        % Button pushed function: FolderSelectButton
        function FolderSelectButtonPushed(app, event)
            app.fileStr = uigetdir;%establishes input file paths and generates trial selection list 
            fileListPop(app);

        end

        % Button pushed function: AcceptButton
        function AcceptButtonPushed(app, event)
            app.tableBuilder = [];
            try
                if (app.isRecorded == false &&app.type=="tsv")
                    app.currentFile = app.fileSel;
                    app.COPfileIn = COPfileInput(fullfile(app.fileStr,app.fileSel));
                    app.COPdata = COPdataSet(app.COPfileIn.get.COPData.data(:,7),...
                        dlmread(fullfile(app.fileStr,app.fileSel),'\t',[1,1,1,1]),...
                        app.COPfileIn.get.COPData.data(:,8));
                    for i = app.COPdata.AP.endTime:-5:5
                        data = changeDur(app.COPdata,i);
                        app.tableBuilder = [app.tableBuilder;blockBuild(data,app.subject,app.Condition,i)];
                    end
                    app.dataTable.Data = app.tableBuilder(1:end,:);
                    plot(app.COPplot,app.COPdata.AP.unfiltForceSet,app.COPdata.ML.unfiltForceSet,'b');
                    hold(app.COPplot,'on');
                    if(app.isUnfiltOn)
                        plot(app.COPplot,get(get(app.COPdata,'AP'),'forceSet'),get(get(app.COPdata,'ML'),'forceSet'),'r');
                    end
                    app.isRecorded = true;
                    hold(app.COPplot,'off');
                elseif((app.isRecorded == false&&app.type=="forces"))
                    app.currentFile = app.fileSel;
                    app.COPfileIn = COPfileInput(fullfile(app.fileStr,app.fileSel));
                    srate = strsplit(char(app.COPfileIn.rawData.textdata(3,1)),'=');
                    srate=srate(end);
                    app.COPdata = COPdataSet(app.COPfileIn.COPData(:,1),... 
                        srate,...
                        app.COPfileIn.COPData(:,2));
                    for i = app.COPdata.AP.endTime:-5:5
                        data = changeDur(app.COPdata,i);
                        app.tableBuilder = [app.tableBuilder;blockBuild(data,app.subject,app.Condition,i)];
                    end
                    app.dataTable.Data = app.tableBuilder(1:end,:);
                    plot(app.COPplot,app.COPdata.AP.unfiltForceSet,app.COPdata.ML.unfiltForceSet,'b');
                    hold(app.COPplot,'on');
                    if(app.isUnfiltOn)
                        plot(app.COPplot,get(get(app.COPdata,'AP'),'forceSet'),get(get(app.COPdata,'ML'),'forceSet'),'r');
                    end
                    app.isRecorded = true;
                    hold(app.COPplot,'off');
                end
            catch
                return;
            end
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            xlswrite(fullfile(app.outpath,app.SubjectEditField.Value),[app.header;app.dataTable.Data],app.Condition);
            app.isRecorded= true;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.fileSel = app.ListBox.Value;

            [app.subject,con] = strtok(app.fileSel,'_');
            app.Condition = strtok(con,'_*_');
            app.fileName = strtok(app.fileSel,'.');
            app.isRecorded = false;
        end

        % Button pushed function: OutputfolderButton
        function OutputfolderButtonPushed(app, event)
            app.outpath = uigetdir;
            app.Output.Text = app.outpath;
        end

        % Close request function: COP
        function COPCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: YMinMaxEditField
        function YMinMaxEditFieldValueChanged(app, event)
            app.COPplot.YLim = [-app.YMinMaxEditField.Value,app.YMinMaxEditField.Value];
            
        end

        % Value changed function: XMinMaxEditField
        function XMinMaxEditFieldValueChanged(app, event)
            app.COPplot.XLim = [-app.XMinMaxEditField.Value,app.XMinMaxEditField.Value];
            
        end

        % Value changed function: IncludeUnfilteredCheckBox
        function IncludeUnfilteredCheckBoxValueChanged(app, event)
            app.isUnfiltOn = app.IncludeUnfilteredCheckBox.Value;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.isRecorded = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COP = uifigure('Visible', 'off');
            app.COP.Name = 'UI Figure';
            app.COP.CloseRequestFcn = createCallbackFcn(app, @COPCloseRequest, true);
            app.COPplot = uiaxes(app.COP);
            title(app.COPplot, 'Title')
            xlabel(app.COPplot, 'X')
            ylabel(app.COPplot, 'Y')
            app.FolderSelectButton = uibutton(app.COP, 'push');
            app.FolderSelectButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelectButtonPushed, true);
            app.FolderSelectButton.Text = 'FolderSelect';
            app.Label = uilabel(app.COP);
            app.Label.Text = '';
            app.AcceptButton = uibutton(app.COP, 'push');
            app.AcceptButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.AcceptButton.Text = 'Accept';
            app.dataTable = uitable(app.COP);
            app.dataTable.ColumnName = {'Column 1'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'; 'Column 10'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.dataTable.RowName = {};
            app.RecordButton = uibutton(app.COP, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.SubjectEditFieldLabel = uilabel(app.COP);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.COP, 'text');
            app.ListBox = uilistbox(app.COP);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.OutputfolderButton = uibutton(app.COP, 'push');
            app.OutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputfolderButtonPushed, true);
            app.OutputfolderButton.Text = 'Output folder';
            app.Output = uilabel(app.COP);
            app.Output.Text = '';
            app.YMinMaxEditFieldLabel = uilabel(app.COP);
            app.YMinMaxEditFieldLabel.Text = 'Y Min/Max';
            app.YMinMaxEditField = uieditfield(app.COP, 'numeric');
            app.YMinMaxEditField.ValueChangedFcn = createCallbackFcn(app, @YMinMaxEditFieldValueChanged, true);
            app.XMinMaxEditFieldLabel = uilabel(app.COP);
            app.XMinMaxEditFieldLabel.Text = 'X Min/Max';
            app.XMinMaxEditField = uieditfield(app.COP, 'numeric');
            app.XMinMaxEditField.ValueChangedFcn = createCallbackFcn(app, @XMinMaxEditFieldValueChanged, true);
            app.IncludeUnfilteredCheckBox = uicheckbox(app.COP);
            app.IncludeUnfilteredCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeUnfilteredCheckBoxValueChanged, true);
            app.IncludeUnfilteredCheckBox.Text = 'Include Unfiltered';
            app.IncludeUnfilteredCheckBox.Value = true;
            app.ResetButton = uibutton(app.COP, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.COP.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COPGuiOOP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COP)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COP)
        end
    end
end